classdef InteractiveListMenu < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        ListLabel       matlab.ui.control.Label
        TreeList        matlab.ui.container.Tree
        AddNewItemNode  matlab.ui.container.TreeNode
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ItemAdded
        ItemRemoved
        ItemSelected
    end

    properties (Access = private)
        ItemContextMenu matlab.ui.container.ContextMenu % Context menu for items
        ItemNodeList matlab.ui.container.TreeNode % List of item nodes to display in list view
    end
    
    properties (Access = public)
        ItemType (1,1) string = "Item"; % A type name for the items in the list
    end
    
    properties
        SelectedItemIndex
        Identifier
    end
    
    methods
        
        function addItem(comp, newItemName)
        % addItem - Add new item to list
            arguments
                comp
                newItemName (1,1) string = missing
            end

            if ismissing(newItemName) || newItemName == ""
                newItemName = comp.createGenericName();
            end

            newListNode = uitreenode(comp.TreeList, 'Text', newItemName);
            newListNode.ContextMenu = comp.ItemContextMenu;

            comp.ItemNodeList = [comp.ItemNodeList, newListNode];
        end
        
        function itemName = getItemName(comp, itemIndex)
            itemName = comp.ItemNodeList(itemIndex).Text;
        end

        function renameItem(comp, itemIndex, newItemName)
            if isstring(newItemName)
                if ismissing(newItemName) || newItemName == ""
                    newItemName = comp.createGenericName();
                end
            end

            if isempty(newItemName)
                newItemName = comp.createGenericName();
            end
            
            comp.ItemNodeList(itemIndex).Text = newItemName;
        end

        function removeItem(comp, itemNode)
            % Todo: Should not notify if this is called programmatically...

            idx = find(comp.ItemNodeList == itemNode);
            if ~isempty(idx)
                comp.ItemNodeList(idx) = [];
    
                % Todo: Update generic labels.
                
                delete(itemNode)
                %comp.notify('ItemRemoved')
                notify(comp, 'ItemRemoved');
                
                fprintf("notify item removed %d\n", idx)
                disp( comp.Identifier )

                if idx <= numel(comp.ItemNodeList)
                    idx = idx; %#ok<ASGSL>
                elseif idx <= numel(comp.ItemNodeList) - 1
                    idx = idx-1;
                else
                    idx = [];
                end
            
                if ~isempty(idx)
                    comp.TreeList.SelectedNodes = comp.ItemNodeList(idx);

                    % Call selection changed callback manually.
                    drawnow % Make sure removeItem callbacks execute before changing selection
                    comp.TreeListSelectionChanged([])

                    hFigure = ancestor(comp, 'figure'); 
                    set(hFigure, 'CurrentObject', comp.ItemNodeList(idx));
                end
            end
        end
    end

    methods
        function set.ItemType(comp, value)
            comp.ItemType = value;
            comp.postSetItemType()
        end
        function set.SelectedItemIndex(comp, value)
            comp.SelectedItemIndex = value;
            comp.postSetSelectedItemIndex()
        end
    end
   
    methods (Access = private)
        function createContextMenu(comp, hFigure)
            if ~isempty(hFigure) && isvalid(hFigure)
                comp.ItemContextMenu = uicontextmenu(hFigure);
                
                removeItemMenuItem = uimenu(comp.ItemContextMenu);
                removeItemMenuItem.Text = "Remove item";
                removeItemMenuItem.Callback = @comp.onRemoveItemContextMenuItemClicked;
            end
        end

        function newItemName = createGenericName(comp)
            newItemName = sprintf( "%s %d", comp.ItemType, numel(comp.TreeList.Children) );
        end

        function onRemoveItemContextMenuItemClicked(comp, ~, evt)
            comp.removeItem(evt.ContextObject);
        end
    end

    % Property postset methods 
    methods (Access = private)
        function postSetItemType(comp)
            comp.ListLabel.Text = sprintf('%s overview', comp.ItemType);
            comp.AddNewItemNode.Text = sprintf('< Add new %s >', lower(comp.ItemType));
            for i = 1:numel(comp.ItemNodeList)
                comp.ItemNodeList(i).Text = strrep(comp.ItemNodeList(i).Text, 'Item', comp.ItemType);
            end
        end

        function postSetSelectedItemIndex(comp)
            comp.TreeList.SelectedNodes = comp.TreeList.Children(comp.SelectedItemIndex+1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            hFigure = ancestor(comp, 'figure');
            comp.createContextMenu(hFigure)
            comp.Identifier = char( matlab.lang.internal.uuid );
        end

        % Selection changed function: TreeList
        function TreeListSelectionChanged(comp, event)
            selectedNodes = comp.TreeList.SelectedNodes;
                                    
            if startsWith(selectedNodes.Text, "< Add new")
                %comp.addItem()
                %comp.notify('ItemAdded')
            else

                idx = find(comp.ItemNodeList == selectedNodes);
                evtData = structeditor.eventdata.ListItemSelectedData(idx, idx, selectedNodes.Text);
                comp.notify('ItemSelected', evtData);
                comp.SelectedItemIndex = idx;
            end
        end

        % Clicked callback: TreeList
        function TreeListClicked(comp, event)
            node = event.InteractionInformation.Node;
            if isempty(node); return; end

            if startsWith(node.Text, "< Add new")
                comp.addItem()
                %drawnow % flush queue?
                comp.notify('ItemAdded')
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
       
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 304 481];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.TreeList = uitree(comp.GridLayout);
            comp.TreeList.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @TreeListSelectionChanged, true);
            comp.TreeList.ClickedFcn = matlab.apps.createCallbackFcn(comp, @TreeListClicked, true);
            comp.AddNewItemNode = uitreenode(comp.TreeList);
            comp.AddNewItemNode.Text = '+ Add new item';
            comp.ListLabel = uilabel(comp.GridLayout);
            comp.ListLabel.Text = 'Item overview';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end